classdef Brain_resection_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalculateButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SliceLabel                      matlab.ui.control.Label
        SliceSlider                     matlab.ui.control.Slider
        SEEDLabel                       matlab.ui.control.Label
        xseedEditFieldLabel             matlab.ui.control.Label
        xseedEditField                  matlab.ui.control.NumericEditField
        yseedEditFieldLabel             matlab.ui.control.Label
        yseedEditField                  matlab.ui.control.NumericEditField
        zseedEditFieldLabel             matlab.ui.control.Label
        zseedEditField                  matlab.ui.control.NumericEditField
        ExportResectiontoFileButton     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        ExporttoWorkspaceButton         matlab.ui.control.Button
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        VIEWLabel                       matlab.ui.control.Label
        VIEWKnob                        matlab.ui.control.DiscreteKnob
        ToleranceField                  matlab.ui.control.EditField
        EditFieldFolder                 matlab.ui.control.EditField
        SelectFolderButton              matlab.ui.control.Button
        EditFieldResectedVolume         matlab.ui.control.EditField
        ExcludebrainventriclesCheckBox  matlab.ui.control.CheckBox
        ChangeRangesButton              matlab.ui.control.Button
        ResectionRangesTextAreaLabel    matlab.ui.control.Label
        res_lim                         matlab.ui.control.TextArea
        EditFieldMRI                    matlab.ui.control.EditField
        SelectMRIpostopButton           matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        EditFieldmask                   matlab.ui.control.EditField
        SelectBrainmaskButton           matlab.ui.control.Button
        MRISelectionDropDownLabel       matlab.ui.control.Label
        MRISelectionDropDown            matlab.ui.control.DropDown
        ToleranceSliderLabel            matlab.ui.control.Label
        ToleranceSlider                 matlab.ui.control.Slider
        LessInclusiveLabel              matlab.ui.control.Label
        MoreInclusiveLabel              matlab.ui.control.Label
        PlaceManuallyButton             matlab.ui.control.Button
        GenerateautomatedreportCheckBox  matlab.ui.control.CheckBox
        EditFieldResectedBrainArea      matlab.ui.control.TextArea
        Show3DButton                    matlab.ui.control.Button
        GenerateautomatedreportgraphsCheckBox  matlab.ui.control.CheckBox
        selected_aseg                   matlab.ui.control.EditField
        SelectpreoperativeparcellationfileButton  matlab.ui.control.Button
        ExporttoAnatomyFolderButton     matlab.ui.control.Button
    end

    properties (Access = private) % BRAIN_RESECTION_APP VERSION 1.0
        resection %This variable will contain the results of the resection
        postop_mri %This variable will contain postop mri
        aseg = 0;
        info
        isdesikan = false;
        popup_range_dialog
        limits = [1,256;1,256;1,256]; %Default region growing limits
        % ADDPATH HERE!
    end
    
    methods (Access = private)
        function run(app) %THIS IS THE MAIN RUN. ALL THE EVENTS CAN MERGE TO THIS FUNCTION
            disp('App is running')
            app.StatusLamp.Color = [0 1 0];
            reg_tol = [0.01 0.02 0.04 0.05 0.06 0.08 0.09];
            seed(1) = app.xseedEditField.Value;
            seed(2) = app.yseedEditField.Value;
            seed(3) = app.zseedEditField.Value;
            app.info.seed = seed;
            app.info.method = 'Local Adjustment';
            [app.postop_mri, mask] = mri_loading(app);
            %Corpus Callosum exclusion (needs ASEG file)
            if app.ExcludebrainventriclesCheckBox.Value
                ventricles_labels = ... %DESIKAN KILLANY ATLAS - EXCLUDING VENTRICLES
                    [app.aseg.Labels{strcmp(app.aseg.Labels(:,2), 'Ventricle lat L')|...
                    strcmp(app.aseg.Labels(:,2), 'Ventricle lat R')|...
                    strcmp(app.aseg.Labels(:,2), 'Ventricle inf-lat L')|...
                    strcmp(app.aseg.Labels(:,2), 'Ventricle inf-lat R')|...
                    ... %FIELDTRIP/SPM ATLAS - EXCLUDING BACKGROUND AND CSF
                    strcmp(app.aseg.Labels(:,2), 'CSF')|...
                    strcmp(app.aseg.Labels(:,2), 'Skull')|...
                    strcmp(app.aseg.Labels(:,2), 'Scalp')|...
                    strcmp(app.aseg.Labels(:,2), 'Background'),1}];
                CC=zeros(size(app.aseg.Cube));
                for i=1:length(ventricles_labels)
                    CC = or(CC,app.aseg.Cube == ventricles_labels(i));
                end
                f = not(and(mask,CC==0)); %Forbidden region is made of background and CC
                app.isdesikan = sum(strcmp(app.aseg.Labels(:,2), 'Background'))<1;
            else; f = not(mask); %Forbidden region is only made by background
            end
            disp('Classes for this patient are:')
            disp(['Brain Mask class = ',class(mask)])
            disp(['Mri postop class = ',class(app.postop_mri.Cube)])
            
            %Standardization and application of region Growing algorithm
            try
                app.info.Tol = reg_tol(round(app.ToleranceSlider.Value)+4);
                switch app.info.method
                    case 'Global Adjustment'
                        mri = standardize_adjn(app,0.1); %Standardization of the Post-op MRI
                        app.resection = reg_grow_app(mri, seed, app.info.Tol, app.limits, f);
                    case 'Local Adjustment'
                        mri = standardize_zadj(app,0.01); %Standardization of the Post-op MRI
                        app.resection = reg_grow_app(mri, seed, app.info.Tol, app.limits, f);
                end
            catch
                disp('Something went wrong with region growing or standardization process')
                app.StatusLamp.Color = [1 0 0];
            end
            if sum(sum(sum(app.resection)))==0
                disp('Please, replace the seed')
            else
                %Smoothing of the results
                se = strel ('sphere', 2);
                app.resection = imclose(app.resection, se);
                app.resection = imbinarize(imgaussfilt3(app.resection,0.5,'FilterSize',11),0.1);
                
                %We will now show the results of the segmentation process
                image_show(app)
                
                %Resected volume in cm^3
                res_volume = (sum(sum(sum(app.resection==max(max(max(app.resection))))))*app.postop_mri.Voxsize(1)...
                    *app.postop_mri.Voxsize(2)*app.postop_mri.Voxsize(3))/1000;
                app.EditFieldResectedVolume.Value = (['Resection volume: ',num2str(round(res_volume,2)), ' cm^3']);
                
                %Resection % calculation
                if isa(app.aseg,'struct') && app.GenerateautomatedreportCheckBox.Value && app.isdesikan
                    res_tissue(app);
                end
            end
        end %End of the calculate run
        
        %Function used in the main program:
        function res_tissue(app)
            lab_code = cell2mat(app.aseg.Labels(:,1)); %Labels from selected atlas
            res_map = app.resection ~= 0; %Resection map
            [tr(:,1),tr(:,2),tr(:,3)] = ind2sub(size(app.aseg.Cube),find(app.aseg.Cube ~= 0)); %We train the KNN by using all known regions
            gt = app.aseg.Cube(find(app.aseg.Cube ~= 0)); %Ground truth
            knn = fitcknn(tr,gt); %Training of the knn model
            [res_vox_unknown(:,1),res_vox_unknown(:,2),res_vox_unknown(:,3)] =...
                ind2sub(size(app.resection),find(app.resection ~= 0 & app.aseg.Cube == 0)); %Unknown pixels within the resection
            lab_in_res = predict(knn,res_vox_unknown); %Labelling unknown pixels within the resection
            aseg.Cube_copy = app.aseg.Cube;
            aseg.Cube_copy(find(app.resection ~= 0 & app.aseg.Cube == 0)) = lab_in_res;
            %Substituing unknown pixel within resection with predicted labels
            
            %Most resected tissues are:
            lab_in_res = aseg.Cube_copy(find(app.resection ~= 0)); %New labels within resection
            resected_areas = unique(lab_in_res);
            n = histcounts(lab_in_res,resected_areas);
            n_tot = histcounts(reshape(aseg.Cube_copy,numel(aseg.Cube_copy),1),resected_areas);
            perc_res = n./n_tot *100;
            [perc_sorted,ind] = sort(perc_res,'descend');
            for i=1:sum(perc_sorted>1)
                perc(i,2) = app.aseg.Labels(find(lab_code==resected_areas(ind(i)),1),2);
                perc(i,1) = {perc_sorted(i)};
            end
            if app.GenerateautomatedreportgraphsCheckBox.Value %PLOT GENERATION
                figure; bar(perc_sorted(1:sum(perc_sorted>1)))
                title('Percentage of resected lobes - KNN to RESECTION approach')
                xticks(1:sum(perc_sorted>1)); xtickangle(45); xticklabels(perc(:,2))
            end
            %Resection is made of:
            perc_in_res = n/sum(n)*100;
            [perc_in_sorted,ind_in] = sort(perc_in_res,'descend');
            for i=1:sum(perc_in_sorted>1)
                perc_in(i,2) = app.aseg.Labels(find(lab_code==resected_areas(ind_in(i)),1),2);
                perc_in(i,1) = {perc_in_sorted(i)};
            end
            if app.GenerateautomatedreportgraphsCheckBox.Value %PLOT GENERATION
                x = perc_in_sorted(1:sum(perc_in_sorted>1)); x(sum(perc_in_sorted>1)+1) =...
                    sum(perc_in_sorted(sum(perc_in_sorted>1)+1:end));
                figure; pie(x); legend([{perc_in{:,2}}.';{'Others'}])
                title('Percentage of tissues inside the resection - KNN to RESECTION approach')
            end
            report = {'Most resected Tissues were:'};
            for i=1:size(perc,1)
                report = [report;{[perc{i,2}, ' - ',num2str(round(perc{i,1})), ' % Removed']}];
            end
            report = [report; {' '};{'Resection was made of:'}];
            for i = 1:size(perc_in,1)
                report = [report;{[perc_in{i,2}, ' - ',num2str(round(perc_in{i,1})), ' % of resected tissue']}];
            end
            app.EditFieldResectedBrainArea.Value = report;
        end
        
        function [mri_post, mask_pre] = mri_loading(app)
            try; mri_post = load([app.info.path_mri,app.EditFieldMRI.Value]);
            catch; disp('There was a problem in loading postoperative MRI')
            end
            if ~strcmp(app.EditFieldmask.Value,'Brain Mask: (Default: no-brainmask)')
                try
                    mask_pre = load([app.info.path_mask,app.EditFieldmask.Value]);
                    mask_pre = imbinarize(imgaussfilt3(mask_pre.Cube,0.5,'FilterSize',13),0.01);
                catch; disp('There was a problem in loading the brain mask')
                end
            else
                mask_pre = ones(size(mri_post.Cube));
            end
        end
        function export_result(app,loc)
            switch app.info.Tol
                case 0.01; tol_str = '001';
                case 0.02; tol_str = '002';
                case 0.04; tol_str = '004';
                case 0.05; tol_str = '005';
                case 0.06;  tol_str = '06';
                case 0.08; tol_str = '08';
                case 0.09; tol_str = '09';
            end
            res = app.postop_mri;
            res.Cube = app.resection;
            res.Comment = ['Resection_tol_',tol_str];
            try
                switch app.info.method
                    case 'Global Adjustment'
                        save(fullfile(loc,['subjectimage_Reg_3Dadj_',tol_str]),'-struct','res');
                    case 'Local Adjustment'
                        save(fullfile(loc,['subjectimage_Reg_Zadj_',tol_str]),'-struct','res');
                end
            catch
            end
        end
        function aseg_selection(app)
            if ~isa(app.aseg,'struct') && (app.GenerateautomatedreportCheckBox.Value || app.ExcludebrainventriclesCheckBox.Value...
                    || strcmp(app.selected_aseg.Value,'Aparc: none') || isequal(app.aseg,0))
                disp('Please, select the ASEG file ')
                
                if strcmp(app.EditFieldFolder.Value,'Folder: (Default: workspace)')
                    [name,path] = uigetfile({'*.mat';'*.nii'});
                else
                    [name,path] = uigetfile({'*.mat';'*.nii'},'File Selector',app.EditFieldFolder.Value);
                end
                if isequal(name,0)
                    disp('No ASEG file was selected')
                    app.selected_aseg.Value = 'Aparc: none'
                else
                    app.aseg = load([path,name]);
                    disp('ASEG file selected and loaded')
                    app.selected_aseg.Value = name;
                    if strcmp(app.EditFieldFolder.Value,'Folder: (Default: workspace)')
                        app.EditFieldFolder.Value = path;
                    end
                end
            end
        end
        
        function [resection_scs] = vox2scs (app)
            %This function is used to go from voxel coordinates to scs coordinates.
            [xyz_vox(:,1), xyz_vox(:,2), xyz_vox(:,3)] = ind2sub(size(app.resection),find(app.resection ~= 0));
            xyz_mri = xyz_vox.*app.postop_mri.Voxsize;
            resection_scs =  zeros(size(xyz_vox));
            for i=1:size(resection_scs)
                resection_scs(i,:) = (app.postop_mri.SCS.R*xyz_mri(i,:)'+app.postop_mri.SCS.T)';
            end
            resection_scs = resection_scs/1000;
        end
        
        function im_std = standardize_adjn(app,tol)
            %This function is used to standardize the input images, in order to
            %minimize the differences in terms of contrast and light
            %Adjust Contrast with imadjustn
            image= app.postop_mri.Cube;
            if isa(image,'single')
                image = double(image)./double(max(max(max(image))));
                image = uint8(image*255);
            end
            low_high = stretchlim(image(:),tol);
            im_std = imadjustn(image,low_high);
            if isa(image,'int16')
                im_std = uint8(im2double(im_std)*255);
            end
        end
        
        function im_std = standardize_zadj(app,tol)
            image= app.postop_mri.Cube;
            if isa(image,'single')
                image = double(image)./double(max(max(max(image))));
                image = uint8(image*255);
            end
            low_high = stretchlim(image,tol);
            for i=1:size(image,3)
                im_std(:,:,i) = imadjust(image(:,:,i),low_high(:,i));
            end
            if isa(image,'int16')
                im_std = uint8(im2double(im_std)*255);
            end
        end
        
        function image_show(app) %Image show function: it display MRIs and resection segmentation results
            slice = round(app.SliceSlider.Value);
            try
                switch app.VIEWKnob.Value
                    case 'Sagittal'
                        if app.info.seed(1) + slice > size(app.postop_mri.Cube,1)
                            slice = size(app.postop_mri.Cube,1) - app.info.seed(1); %If the slide exeed maximum dimensions, we plot the last slice
                        elseif app.info.seed(1) + slice < 1
                            slice = 1 + app.info.seed(1); %Lowest plottable slice is the first one
                        end
                        im = reshape(app.postop_mri.Cube(app.info.seed(1)+slice,:,:),...
                            [size(app.postop_mri.Cube,1),...
                            size(app.postop_mri.Cube,3),1]);
                        res = reshape(app.resection(app.info.seed(1)+slice,:,:),...
                            [size(app.postop_mri.Cube,1)...
                            size(app.postop_mri.Cube,3),1]);
                        mri = imrotate(im,90);
                        %%%%% MRI VIEW ADJUSTMENT
                        if isa(mri,'single')
                            mri = double(mri)./double(max(max(max(mri))));
                            mri = uint8(mri*255);
                        elseif isa(mri,'int16')
                            mri = uint8(im2double(imadjust(mri))*255);
                        else
                            mri = imadjust(mri,90);
                        end
                        %%%%
                        resect = imrotate(uint8(res*255),90);
                        imshow(mri,'Parent',app.UIAxes)
                        imshow(resect,'Parent',app.UIAxes_2)
                    case 'Coronal'
                        if app.info.seed(2) + slice > size(app.postop_mri.Cube,2)
                            slice = size(app.postop_mri.Cube,2) - app.info.seed(2); %If the slide exeed maximum dimensions, we plot the last slice
                        elseif app.info.seed(2) + slice < 1
                            slice = 1 + app.info.seed(2);
                        end
                        im = reshape(app.postop_mri.Cube(:,app.info.seed(2)+slice,:),...
                            [size(app.postop_mri.Cube,1),...
                            size(app.postop_mri.Cube,3),1]);
                        res = reshape(app.resection(:,app.info.seed(2)+slice,:),...
                            [size(app.postop_mri.Cube,1),...
                            size(app.postop_mri.Cube,3),1]);
                        mri = imrotate(im,90);
                        %%%%% MRI VIEW ADJUSTMENT
                        if isa(mri,'single')
                            mri = double(mri)./double(max(max(max(mri))));
                            mri = uint8(mri*255);
                        elseif isa(mri,'int16')
                            mri = uint8(im2double(imadjust(mri))*255);
                        else
                            mri = imadjust(mri);
                        end
                       %%%%
                        resect = imrotate(uint8(res*255),90);
                        imshow(mri,'Parent',app.UIAxes)
                        imshow(resect,'Parent',app.UIAxes_2)
                    case 'Axial'
                        if app.info.seed(3) + slice > size(app.postop_mri.Cube,3)
                            slice = size(app.postop_mri.Cube,3) - app.info.seed(3); %If the slide exeed maximum dimensions, we plot the last slice
                        elseif app.info.seed(3) + slice < 1
                            slice = 1 + app.info.seed(3);
                        end
                        mri = imrotate(app.postop_mri.Cube(:,:,app.info.seed(3)+slice),90);
                        %%%%% MRI VIEW ADJUSTMENT
                        if isa(mri,'single')
                            mri = double(mri)./double(max(max(max(mri))));
                            mri = uint8(mri*255);
                        elseif isa(mri,'int16')
                            mri = uint8(im2double(imadjust(mri))*255);
                        else
                            mri = imadjust(mri);
                        end
                        %%%%
                        imshow(mri,'Parent',app.UIAxes)
                        imshow(imrotate(app.resection(:,:,app.info.seed(3)+slice),90),'Parent',app.UIAxes_2)
                end
            catch
                disp ('Something went wrong with the image showing function')
                app.StatusLamp.Color = [1 0 0];
            end
        end
    end
    
    methods (Access = public)
        function updateplot(app,new_limits)
            app.limits = new_limits;
            app.res_lim.Value = [{['X: from ',num2str(new_limits(1,1)), ' to ', num2str(new_limits(1,2))]};...
                {['Y: from ', num2str(new_limits(2,1)), ' to ', num2str(new_limits(2,2))]};...
                {['Z: from ', num2str(new_limits(3,1)), ' to ', num2str(new_limits(3,2))]}];
            app.ChangeRangesButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ChangeRangesButton.Enable = 'on';
            disp('App is starting, Remember to open Brainstorm')
            %This section will locate the Brain_resection_app folder and
            %will save the path
            path = which('Brain_resection_app');
            addpath(fileparts(path))
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            run(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.EditFieldFolder.Value = 'Folder: (Default: workspace)';
            app.EditFieldMRI.Value = 'Post-op MRI: (Default: workspace)';
            app.EditFieldmask.Value = 'Brain Mask: (Default: no-brainmask)';
            app.VIEWKnob.Value = 'Sagittal';
            app.EditFieldResectedVolume.Value = 'Resected Volume: ';
            app.EditFieldResectedBrainArea.Value = [{'Resected Brain areas:'}; {' '}; {'Resection was made of:'}];
            app.MRISelectionDropDown.Value = 'Select Folder';
            MRISelectionDropDownValueChanged(app, event)
            app.ToleranceSlider.Value = 0;
            app.ToleranceField.Value = num2str(0.05);
            app.xseedEditField.Value = 0;
            app.yseedEditField.Value = 0;
            app.zseedEditField.Value = 0;
            app.aseg=0;
            new_limits = [1,256;1,256;1,256]; updateplot(app,new_limits);
            app.ExcludebrainventriclesCheckBox.Value = 0;
            app.GenerateautomatedreportCheckBox.Value = 0;
            app.GenerateautomatedreportgraphsCheckBox.Value = 0;
            app.SliceSlider.Value = 0;
            app.resection = []; app.postop_mri = []; app.info = [];
            app.selected_aseg.Value ='Aseg: none selected';
            app.StatusLamp.Color = [0 1 0];
            cla(app.UIAxes)
            cla(app.UIAxes_2)
        end

        % Button pushed function: ExportResectiontoFileButton
        function ExportResectiontoFileButtonPushed(app, event)
            try
                res = app.postop_mri;
                res.Cube = app.resection;
            catch
            end
            if (isempty(app.resection)==0)
                loc = uigetdir;
                export_result(app,loc);
            else
                disp('Nothing to export: please, use calculate before exporting')
            end
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            try
                if ~isempty(app.resection)
                    resection_scs = vox2scs(app);
                    labels = {'Export to Matlab Resection Vox Map as:' ...
                        'Export to Matlab Resection SCS as:' 'Export to Matlab Segmentation info as:'};
                    vars = {'resection_vox','resection_scs','segm_info'};
                    vals = {app.resection, resection_scs, app.info};
                    title = ('Export Variables to Workspace');
                    defs = logical([0 0 0]);
                    export2wsdlg(labels,vars,vals,...
                        title,defs);
                else
                    disp ('Nothing to export: please, use calculate before exporting')
                    app.StatusLamp.Color = [1 0 0];
                end
            catch
                disp ('Something went wrong')
                app.StatusLamp.Color = [1 0 0];
            end
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            if (isempty(app.resection)==0) %We will do an update of the images
                try; image_show(app);
                catch; disp ('Something went wrong when updating the plots'); app.StatusLamp.Color = [1 0 0];
                end
            end
        end

        % Value changed function: VIEWKnob
        function VIEWKnobValueChanged(app, event)
            SliceSliderValueChanged(app, event);
        end

        % Value changed function: MRISelectionDropDown
        function MRISelectionDropDownValueChanged(app, event)
            if strcmp(app.MRISelectionDropDown.Value,'Select Folder')
                app.EditFieldFolder.Enable ='on';
                app.EditFieldMRI.Enable = 'off';
                app.EditFieldmask.Enable = 'off';
            else
                app.EditFieldFolder.Enable ='off';
                app.EditFieldMRI.Enable = 'on';
                app.EditFieldmask.Enable = 'on';
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.MRISelectionDropDown.Value = 'Select Folder';
            MRISelectionDropDownValueChanged(app, event)
            disp('Please, select the folder ')
            
            if strcmp(app.EditFieldFolder.Value,'Folder: (Default: workspace)')
                path = uigetdir;
            else
                path = uigetdir(app.EditFieldFolder.Value);
            end
            if isequal(path,0)
                disp('No folder was selected')
            else
                app.EditFieldFolder.Value = path;
                app.info.path_mri = path;
                %Search for file in the brainstorm anatomy folder
                files = dir (app.EditFieldFolder.Value);
                files = {files.name};
                for i = 1:size(files,2)
                    l = length(files{i});
                    if l>=23 %Change the name of the post-op MRI on the following line
                        if strcmp(files{i}(1:23),'subjectimage_MRI_postop'); mri_name = files{i};
                        end
                    end
                    if l>=22 %Change the names of the brainmask on the following line
                        if strcmp(files{i}(1:22),'subjectimage_brainmask'); mask_name = files{i};
                        end
                    end
                end
                if exist('mri_name','var')
                    app.EditFieldMRI.Value = mri_name;
                end
                if exist('mask_name','var')
                    app.EditFieldmask.Value = mask_name;
                end
                app.info.path_mri = [app.EditFieldFolder.Value,'\'];
                app.info.path_mask = app.info.path_mri;
            end
        end

        % Button pushed function: SelectMRIpostopButton
        function SelectMRIpostopButtonPushed(app, event)
            app.MRISelectionDropDown.Value = 'Select Files';
            MRISelectionDropDownValueChanged(app, event)
            disp('Please, select the Post-Operative MRI file ')
            
            if strcmp(app.EditFieldFolder.Value,'Folder: (Default: workspace)')
                [val,path] = uigetfile({'*.mat';'*.nii'});
            else
                [val,path] = uigetfile({'*.mat';'*.nii'},'File Selector',app.info.path_mri);
            end
            if isequal(val,0)
                disp('No MRI was selected')
            else
                app.EditFieldMRI.Value = val;
                app.info.path_mri = path;
                disp('MRI selected')
            end
            
        end

        % Button pushed function: SelectBrainmaskButton
        function SelectBrainmaskButtonPushed(app, event)
            app.MRISelectionDropDown.Value = 'Select Files';
            MRISelectionDropDownValueChanged(app, event)
            disp('Please, select the Pre-Operative Brain Mask file ')
            if strcmp(app.EditFieldFolder.Value,'Folder: (Default: workspace)')
                [val,path] = uigetfile({'*.mat';'*.nii'});
            else
                [val,path] = uigetfile({'*.mat';'*.nii'},'File Selector',app.info.path_mri);
            end
            if isequal(val,0)
                disp('No brain mask was selected')
            else
                app.EditFieldmask.Value = val;
                app.info.path_mask = path;
                disp('Brain mask selected')
                if strcmp(app.EditFieldFolder.Value,'Folder: (Default: workspace)')
                    app.info.path_mri = path;
                end
            end
        end

        % Button pushed function: ChangeRangesButton
        function ChangeRangesButtonPushed(app, event)
            app.ChangeRangesButton.Enable = 'off';
            app.popup_range_dialog = popup_range(app,app.limits);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.popup_range_dialog)
            delete(app)
        end

        % Button pushed function: PlaceManuallyButton
        function PlaceManuallyButtonPushed(app, event)
            if  strcmp(app.EditFieldMRI.Value,'Post-op MRI: (Default: workspace)')==0
                if isfield(app.info,'path_mri')==0
                    app.info.path_mri = [app.EditFieldFolder.Value,'\'];
                end
                sl=find(app.info.path_mri=='\'|app.info.path_mri=='/'); %%%%
                pat_folder = app.info.path_mri(sl(end-1)+1:end);%%%%%
                hFig = view_mri([pat_folder,app.EditFieldMRI.Value])
                %hFig = view_mri([app.info.path_mri,app.EditFieldMRI.Value])
                [sMri,handl] = figure_mri('SetupMri',hFig);
                cs= {'Voxel'};
                disp('Place crosshairs on the seed and then press ENTER')
                pause
                [seed] = figure_mri('GetLocation',cs, sMri,handl);
                app.xseedEditField.Value = seed(1);
                app.yseedEditField.Value = seed(2);
                app.zseedEditField.Value = seed(3);
            else
                disp('Please, select Post-op MRI first')
            end
            
        end

        % Value changed function: GenerateautomatedreportCheckBox
        function GenerateautomatedreportCheckBoxValueChanged(app, event)
            if app.GenerateautomatedreportCheckBox.Value==false
                app.GenerateautomatedreportgraphsCheckBox.Value = false;
            end
            if isequal(app.aseg,0)
                aseg_selection(app);
            end
        end

        % Value changing function: ToleranceSlider
        function ToleranceSliderValueChanging(app, event)
            reg_tol = [0.01 0.02 0.04 0.05 0.06 0.08 0.09];
            app.ToleranceField.Value = num2str(reg_tol(round(app.ToleranceSlider.Value)+4));
        end

        % Value changed function: ToleranceSlider
        function ToleranceSliderValueChanged(app, event)
            reg_tol = [0.01 0.02 0.04 0.05 0.06 0.08 0.09];
            app.ToleranceField.Value = num2str(reg_tol(round(app.ToleranceSlider.Value)+4));
        end

        % Value changed function: ExcludebrainventriclesCheckBox
        function ExcludebrainventriclesCheckBoxValueChanged(app, event)
            GenerateautomatedreportCheckBoxValueChanged(app, event);
        end

        % Button pushed function: Show3DButton
        function Show3DButtonPushed(app, event)
            if ~isempty(app.resection)
                try
                    resection_scs = vox2scs(app);
                    sl=find(app.info.path_mri=='\'|app.info.path_mri=='/'); %%%%
                    pat_folder = app.info.path_mri(sl(end-1)+1:end);%%%%
                    view_mri_3d([pat_folder,app.EditFieldMRI.Value])
                    hold on; scatter3(resection_scs(:,1),resection_scs(:,2),resection_scs(:,3),30,'filled','r')
                catch
                    disp('Unfortunately, there was an error with the scs coordinates')
                end
            else
                disp('Please, use calculate before this ')
                app.StatusLamp.Color = [1 0 0];
            end
        end

        % Value changed function: 
        % GenerateautomatedreportgraphsCheckBox
        function GenerateautomatedreportgraphsCheckBoxValueChanged(app, event)
            app.GenerateautomatedreportCheckBox.Value = true;
            GenerateautomatedreportCheckBoxValueChanged(app, event);
        end

        % Button pushed function: 
        % SelectpreoperativeparcellationfileButton
        function SelectpreoperativeparcellationfileButtonPushed(app, event)
            app.aseg=0;
            aseg_selection(app);
        end

        % Button pushed function: ExporttoAnatomyFolderButton
        function ExporttoAnatomyFolderButtonPushed(app, event)
            try
                res = app.postop_mri;
                res.Cube = app.resection;
            catch
            end
            if (isempty(app.resection)==0 && strcmp(app.EditFieldFolder.Value,'Folder: (Default: workspace)')==0)
                loc = app.EditFieldFolder.Value;
                export_result(app,loc);
            else
                disp('Nothing to export: please, use calculate before exporting')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SliceLabel = uilabel(app.UIFigure);
            app.SliceLabel.Text = {'Slice'; ''};
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.Limits = [-50 50];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.MinorTicks = [];
            app.SEEDLabel = uilabel(app.UIFigure);
            app.SEEDLabel.Text = 'SEED:';
            app.xseedEditFieldLabel = uilabel(app.UIFigure);
            app.xseedEditFieldLabel.Text = 'x seed';
            app.xseedEditField = uieditfield(app.UIFigure, 'numeric');
            app.yseedEditFieldLabel = uilabel(app.UIFigure);
            app.yseedEditFieldLabel.Text = 'y seed';
            app.yseedEditField = uieditfield(app.UIFigure, 'numeric');
            app.zseedEditFieldLabel = uilabel(app.UIFigure);
            app.zseedEditFieldLabel.Text = 'z seed';
            app.zseedEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExportResectiontoFileButton = uibutton(app.UIFigure, 'push');
            app.ExportResectiontoFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResectiontoFileButtonPushed, true);
            app.ExportResectiontoFileButton.Text = 'Export  Resection to File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Postop MRI')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Resection')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.ExporttoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Text = 'Export to Workspace';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.VIEWLabel = uilabel(app.UIFigure);
            app.VIEWLabel.Text = 'VIEW:';
            app.VIEWKnob = uiknob(app.UIFigure, 'discrete');
            app.VIEWKnob.Items = {'Sagittal', 'Coronal', 'Axial'};
            app.VIEWKnob.ValueChangedFcn = createCallbackFcn(app, @VIEWKnobValueChanged, true);
            app.VIEWKnob.Value = 'Sagittal';
            app.ToleranceField = uieditfield(app.UIFigure, 'text');
            app.ToleranceField.Enable = 'off';
            app.ToleranceField.Value = '0.05';
            app.EditFieldFolder = uieditfield(app.UIFigure, 'text');
            app.EditFieldFolder.Value = 'Folder: (Default: workspace)';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.EditFieldResectedVolume = uieditfield(app.UIFigure, 'text');
            app.EditFieldResectedVolume.Enable = 'off';
            app.EditFieldResectedVolume.Value = 'Resected Volume: ';
            app.ExcludebrainventriclesCheckBox = uicheckbox(app.UIFigure);
            app.ExcludebrainventriclesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludebrainventriclesCheckBoxValueChanged, true);
            app.ExcludebrainventriclesCheckBox.Text = 'Exclude brain ventricles';
            app.ChangeRangesButton = uibutton(app.UIFigure, 'push');
            app.ChangeRangesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeRangesButtonPushed, true);
            app.ChangeRangesButton.Text = 'Change Ranges';
            app.ResectionRangesTextAreaLabel = uilabel(app.UIFigure);
            app.ResectionRangesTextAreaLabel.Text = 'Resection Ranges:';
            app.res_lim = uitextarea(app.UIFigure);
            app.res_lim.Editable = 'off';
            app.res_lim.Enable = 'off';
            app.res_lim.Value = {'X: from 1 to 256'; 'Y: from 1 to 256'; 'Z: from 1 to 256'};
            app.EditFieldMRI = uieditfield(app.UIFigure, 'text');
            app.EditFieldMRI.Enable = 'off';
            app.EditFieldMRI.Value = 'Post-op MRI: (Default: workspace)';
            app.SelectMRIpostopButton = uibutton(app.UIFigure, 'push');
            app.SelectMRIpostopButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMRIpostopButtonPushed, true);
            app.SelectMRIpostopButton.Text = 'Select MRI postop';
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR:';
            app.EditFieldmask = uieditfield(app.UIFigure, 'text');
            app.EditFieldmask.Enable = 'off';
            app.EditFieldmask.Value = 'Brain Mask: (Default: no-brainmask)';
            app.SelectBrainmaskButton = uibutton(app.UIFigure, 'push');
            app.SelectBrainmaskButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBrainmaskButtonPushed, true);
            app.SelectBrainmaskButton.Text = 'Select Brain-mask';
            app.MRISelectionDropDownLabel = uilabel(app.UIFigure);
            app.MRISelectionDropDownLabel.Text = 'MRI Selection';
            app.MRISelectionDropDown = uidropdown(app.UIFigure);
            app.MRISelectionDropDown.Items = {'Select Folder', 'Select Files'};
            app.MRISelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MRISelectionDropDownValueChanged, true);
            app.MRISelectionDropDown.Value = 'Select Folder';
            app.ToleranceSliderLabel = uilabel(app.UIFigure);
            app.ToleranceSliderLabel.Text = 'Tolerance';
            app.ToleranceSlider = uislider(app.UIFigure);
            app.ToleranceSlider.Limits = [-3 3];
            app.ToleranceSlider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.ToleranceSlider.ValueChangedFcn = createCallbackFcn(app, @ToleranceSliderValueChanged, true);
            app.ToleranceSlider.ValueChangingFcn = createCallbackFcn(app, @ToleranceSliderValueChanging, true);
            app.ToleranceSlider.MinorTicks = [];
            app.LessInclusiveLabel = uilabel(app.UIFigure);
            app.LessInclusiveLabel.Text = 'Less Inclusive';
            app.MoreInclusiveLabel = uilabel(app.UIFigure);
            app.MoreInclusiveLabel.Text = 'More Inclusive';
            app.PlaceManuallyButton = uibutton(app.UIFigure, 'push');
            app.PlaceManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceManuallyButtonPushed, true);
            app.PlaceManuallyButton.Text = 'Place Manually';
            app.GenerateautomatedreportCheckBox = uicheckbox(app.UIFigure);
            app.GenerateautomatedreportCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenerateautomatedreportCheckBoxValueChanged, true);
            app.GenerateautomatedreportCheckBox.Text = 'Generate automated report';
            app.EditFieldResectedBrainArea = uitextarea(app.UIFigure);
            app.EditFieldResectedBrainArea.Editable = 'off';
            app.EditFieldResectedBrainArea.Enable = 'off';
            app.EditFieldResectedBrainArea.Value = {'Resected Brain areas:'; ''; 'Resection was made of:'};
            app.Show3DButton = uibutton(app.UIFigure, 'push');
            app.Show3DButton.ButtonPushedFcn = createCallbackFcn(app, @Show3DButtonPushed, true);
            app.Show3DButton.Text = 'Show 3D';
            app.GenerateautomatedreportgraphsCheckBox = uicheckbox(app.UIFigure);
            app.GenerateautomatedreportgraphsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenerateautomatedreportgraphsCheckBoxValueChanged, true);
            app.GenerateautomatedreportgraphsCheckBox.Text = 'Generate automated report graphs';
            app.selected_aseg = uieditfield(app.UIFigure, 'text');
            app.selected_aseg.Enable = 'off';
            app.selected_aseg.Value = 'Aparc: none';
            app.SelectpreoperativeparcellationfileButton = uibutton(app.UIFigure, 'push');
            app.SelectpreoperativeparcellationfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpreoperativeparcellationfileButtonPushed, true);
            app.SelectpreoperativeparcellationfileButton.Text = {'Select pre-operative'; 'parcellation file'};
            app.ExporttoAnatomyFolderButton = uibutton(app.UIFigure, 'push');
            app.ExporttoAnatomyFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoAnatomyFolderButtonPushed, true);
            app.ExporttoAnatomyFolderButton.Text = 'Export to Anatomy Folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Brain_resection_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end