classdef popup_range < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SaveButton             matlab.ui.control.Button
        xseedEditFieldLabel    matlab.ui.control.Label
        xseedEditField         matlab.ui.control.NumericEditField
        xseedEditField_2Label  matlab.ui.control.Label
        xseedEditField_2       matlab.ui.control.NumericEditField
        xseedEditField_3Label  matlab.ui.control.Label
        xseedEditField_3       matlab.ui.control.NumericEditField
        XstartEditFieldLabel   matlab.ui.control.Label
        XstartEditField        matlab.ui.control.NumericEditField
        XendEditFieldLabel     matlab.ui.control.Label
        XendEditField          matlab.ui.control.NumericEditField
        YstartEditFieldLabel   matlab.ui.control.Label
        YstartEditField        matlab.ui.control.NumericEditField
        YendEditFieldLabel     matlab.ui.control.Label
        YendEditField          matlab.ui.control.NumericEditField
        ZstartEditFieldLabel   matlab.ui.control.Label
        ZstartEditField        matlab.ui.control.NumericEditField
        ZendEditFieldLabel     matlab.ui.control.Label
        ZendEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, limits)
            app.CallingApp = mainapp;
            app.XstartEditField.Value = limits(1,1);
            app.XendEditField.Value = limits(1,2);
            app.YstartEditField.Value = limits(2,1);
            app.YendEditField.Value = limits(2,2);
            app.ZstartEditField.Value = limits(3,1);
            app.ZendEditField.Value = limits(3,2);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            new_limits = [app.XstartEditField.Value, app.XendEditField.Value;...
                app.YstartEditField.Value, app.YendEditField.Value;...
                app.ZstartEditField.Value, app.ZendEditField.Value];               
            updateplot(app.CallingApp,new_limits)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.OptionsButton.Enable ='on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.xseedEditFieldLabel = uilabel(app.UIFigure);
            app.xseedEditFieldLabel.Text = 'x seed';
            app.xseedEditField = uieditfield(app.UIFigure, 'numeric');
            app.xseedEditField_2Label = uilabel(app.UIFigure);
            app.xseedEditField_2Label.Text = 'x seed';
            app.xseedEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.xseedEditField_3Label = uilabel(app.UIFigure);
            app.xseedEditField_3Label.Text = 'x seed';
            app.xseedEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.XstartEditFieldLabel = uilabel(app.UIFigure);
            app.XstartEditFieldLabel.Text = 'X start';
            app.XstartEditField = uieditfield(app.UIFigure, 'numeric');
            app.XstartEditField.Value = 1;
            app.XendEditFieldLabel = uilabel(app.UIFigure);
            app.XendEditFieldLabel.Text = 'X end';
            app.XendEditField = uieditfield(app.UIFigure, 'numeric');
            app.XendEditField.Value = 256;
            app.YstartEditFieldLabel = uilabel(app.UIFigure);
            app.YstartEditFieldLabel.Text = 'Y start';
            app.YstartEditField = uieditfield(app.UIFigure, 'numeric');
            app.YstartEditField.Value = 1;
            app.YendEditFieldLabel = uilabel(app.UIFigure);
            app.YendEditFieldLabel.Text = 'Y end';
            app.YendEditField = uieditfield(app.UIFigure, 'numeric');
            app.YendEditField.Value = 256;
            app.ZstartEditFieldLabel = uilabel(app.UIFigure);
            app.ZstartEditFieldLabel.Text = 'Z start';
            app.ZstartEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZstartEditField.Value = 1;
            app.ZendEditFieldLabel = uilabel(app.UIFigure);
            app.ZendEditFieldLabel.Text = 'Z end';
            app.ZendEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZendEditField.Value = 256;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = popup_range(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end